classdef SynthUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimbreControls1Tab              matlab.ui.container.Tab
        CoarseFrequencyPanel            matlab.ui.container.Panel
        Osc1KnobLabel                   matlab.ui.control.Label
        Osc1Knob                        matlab.ui.control.DiscreteKnob
        Osc6KnobLabel                   matlab.ui.control.Label
        Osc6Knob                        matlab.ui.control.DiscreteKnob
        Osc5KnobLabel                   matlab.ui.control.Label
        Osc5Knob                        matlab.ui.control.DiscreteKnob
        Osc2KnobLabel                   matlab.ui.control.Label
        Osc2Knob                        matlab.ui.control.DiscreteKnob
        Osc3KnobLabel                   matlab.ui.control.Label
        Osc3Knob                        matlab.ui.control.DiscreteKnob
        Osc4KnobLabel                   matlab.ui.control.Label
        Osc4Knob                        matlab.ui.control.DiscreteKnob
        FineFrequencyPanel              matlab.ui.container.Panel
        Osc1Knob_2Label                 matlab.ui.control.Label
        Osc1Knob_2                      matlab.ui.control.Knob
        Osc2Knob_2Label                 matlab.ui.control.Label
        Osc2Knob_2                      matlab.ui.control.Knob
        Osc3Knob_2Label                 matlab.ui.control.Label
        Osc3Knob_2                      matlab.ui.control.Knob
        Osc4Knob_2Label                 matlab.ui.control.Label
        Osc4Knob_2                      matlab.ui.control.Knob
        Osc5Knob_2Label                 matlab.ui.control.Label
        Osc5Knob_2                      matlab.ui.control.Knob
        Osc6Knob_2Label                 matlab.ui.control.Label
        Osc6Knob_2                      matlab.ui.control.Knob
        ModEnvAmtPanel                  matlab.ui.container.Panel
        Osc1Knob_3Label                 matlab.ui.control.Label
        Osc1Knob_3                      matlab.ui.control.Knob
        Osc2Knob_3Label                 matlab.ui.control.Label
        Osc2Knob_3                      matlab.ui.control.Knob
        Osc3Knob_3Label                 matlab.ui.control.Label
        Osc3Knob_3                      matlab.ui.control.Knob
        Osc4Knob_3Label                 matlab.ui.control.Label
        Osc4Knob_3                      matlab.ui.control.Knob
        Osc5Knob_3Label                 matlab.ui.control.Label
        Osc5Knob_3                      matlab.ui.control.Knob
        Osc6Knob_3Label                 matlab.ui.control.Label
        Osc6Knob_3                      matlab.ui.control.Knob
        OutputLevelsPanel               matlab.ui.container.Panel
        Osc1SliderLabel                 matlab.ui.control.Label
        Osc1Slider                      matlab.ui.control.Slider
        Osc2SliderLabel                 matlab.ui.control.Label
        Osc2Slider                      matlab.ui.control.Slider
        Osc3SliderLabel                 matlab.ui.control.Label
        Osc3Slider                      matlab.ui.control.Slider
        Osc4SliderLabel                 matlab.ui.control.Label
        Osc4Slider                      matlab.ui.control.Slider
        Osc5SliderLabel                 matlab.ui.control.Label
        Osc5Slider                      matlab.ui.control.Slider
        Osc6SliderLabel                 matlab.ui.control.Label
        Osc6Slider                      matlab.ui.control.Slider
        LFOADepthPanel                  matlab.ui.container.Panel
        Osc1Knob_4Label                 matlab.ui.control.Label
        Osc1Knob_4                      matlab.ui.control.Knob
        Osc2Knob_4Label                 matlab.ui.control.Label
        Osc2Knob_4                      matlab.ui.control.Knob
        Osc3Knob_4Label                 matlab.ui.control.Label
        Osc3Knob_4                      matlab.ui.control.Knob
        Osc4Knob_4Label                 matlab.ui.control.Label
        Osc4Knob_4                      matlab.ui.control.Knob
        Osc5Knob_4Label                 matlab.ui.control.Label
        Osc5Knob_4                      matlab.ui.control.Knob
        Osc6Knob_4Label                 matlab.ui.control.Label
        Osc6Knob_4                      matlab.ui.control.Knob
        LFOBDepthPanel                  matlab.ui.container.Panel
        Osc1Knob_5Label                 matlab.ui.control.Label
        Osc1Knob_5                      matlab.ui.control.Knob
        Osc2Knob_5Label                 matlab.ui.control.Label
        Osc2Knob_5                      matlab.ui.control.Knob
        Osc3Knob_5Label                 matlab.ui.control.Label
        Osc3Knob_5                      matlab.ui.control.Knob
        Osc4Knob_5Label                 matlab.ui.control.Label
        Osc4Knob_5                      matlab.ui.control.Knob
        Osc5Knob_5Label                 matlab.ui.control.Label
        Osc5Knob_5                      matlab.ui.control.Knob
        Osc6Knob_5Label                 matlab.ui.control.Label
        Osc6Knob_5                      matlab.ui.control.Knob
        TimbreControls2Tab              matlab.ui.container.Tab
        PhaseModulationParametersPanel  matlab.ui.container.Panel
        Osc11KnobLabel                  matlab.ui.control.Label
        Osc11Knob                       matlab.ui.control.Knob
        Osc12KnobLabel                  matlab.ui.control.Label
        Osc12Knob                       matlab.ui.control.Knob
        Osc13KnobLabel                  matlab.ui.control.Label
        Osc13Knob                       matlab.ui.control.Knob
        Osc14KnobLabel                  matlab.ui.control.Label
        Osc14Knob                       matlab.ui.control.Knob
        Osc15KnobLabel                  matlab.ui.control.Label
        Osc15Knob                       matlab.ui.control.Knob
        Osc16KnobLabel                  matlab.ui.control.Label
        Osc16Knob                       matlab.ui.control.Knob
        Osc21KnobLabel                  matlab.ui.control.Label
        Osc21Knob                       matlab.ui.control.Knob
        Osc22KnobLabel                  matlab.ui.control.Label
        Osc22Knob                       matlab.ui.control.Knob
        Osc23KnobLabel                  matlab.ui.control.Label
        Osc23Knob                       matlab.ui.control.Knob
        Osc24KnobLabel                  matlab.ui.control.Label
        Osc24Knob                       matlab.ui.control.Knob
        Osc25KnobLabel                  matlab.ui.control.Label
        Osc25Knob                       matlab.ui.control.Knob
        Osc26KnobLabel                  matlab.ui.control.Label
        Osc26Knob                       matlab.ui.control.Knob
        Osc31KnobLabel                  matlab.ui.control.Label
        Osc31Knob                       matlab.ui.control.Knob
        Osc32KnobLabel                  matlab.ui.control.Label
        Osc32Knob                       matlab.ui.control.Knob
        Osc33KnobLabel                  matlab.ui.control.Label
        Osc33Knob                       matlab.ui.control.Knob
        Osc34KnobLabel                  matlab.ui.control.Label
        Osc34Knob                       matlab.ui.control.Knob
        Osc35KnobLabel                  matlab.ui.control.Label
        Osc35Knob                       matlab.ui.control.Knob
        Osc36KnobLabel                  matlab.ui.control.Label
        Osc36Knob                       matlab.ui.control.Knob
        Osc41KnobLabel                  matlab.ui.control.Label
        Osc41Knob                       matlab.ui.control.Knob
        Osc42KnobLabel                  matlab.ui.control.Label
        Osc42Knob                       matlab.ui.control.Knob
        Osc43KnobLabel                  matlab.ui.control.Label
        Osc43Knob                       matlab.ui.control.Knob
        Osc44KnobLabel                  matlab.ui.control.Label
        Osc44Knob                       matlab.ui.control.Knob
        Osc45KnobLabel                  matlab.ui.control.Label
        Osc45Knob                       matlab.ui.control.Knob
        Osc46KnobLabel                  matlab.ui.control.Label
        Osc46Knob                       matlab.ui.control.Knob
        Osc51KnobLabel                  matlab.ui.control.Label
        Osc51Knob                       matlab.ui.control.Knob
        Osc52KnobLabel                  matlab.ui.control.Label
        Osc52Knob                       matlab.ui.control.Knob
        Osc53KnobLabel                  matlab.ui.control.Label
        Osc53Knob                       matlab.ui.control.Knob
        Osc54KnobLabel                  matlab.ui.control.Label
        Osc54Knob                       matlab.ui.control.Knob
        Osc55KnobLabel                  matlab.ui.control.Label
        Osc55Knob                       matlab.ui.control.Knob
        Osc56KnobLabel                  matlab.ui.control.Label
        Osc56Knob                       matlab.ui.control.Knob
        Osc61KnobLabel                  matlab.ui.control.Label
        Osc61Knob                       matlab.ui.control.Knob
        Osc62KnobLabel                  matlab.ui.control.Label
        Osc62Knob                       matlab.ui.control.Knob
        Osc63KnobLabel                  matlab.ui.control.Label
        Osc63Knob                       matlab.ui.control.Knob
        Osc64KnobLabel                  matlab.ui.control.Label
        Osc64Knob                       matlab.ui.control.Knob
        Osc65KnobLabel                  matlab.ui.control.Label
        Osc65Knob                       matlab.ui.control.Knob
        Osc66KnobLabel                  matlab.ui.control.Label
        Osc66Knob                       matlab.ui.control.Knob
        TimeControlsTab                 matlab.ui.container.Tab
        LFOAPanel_2                     matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        RateHzKnob_3Label               matlab.ui.control.Label
        RateHzKnob_3                    matlab.ui.control.Knob
        AmpKnob_3Label                  matlab.ui.control.Label
        AmpKnob_3                       matlab.ui.control.Knob
        PhaseSpreadKnob_2Label          matlab.ui.control.Label
        PhaseSpreadKnob_2               matlab.ui.control.Knob
        LFOBPanel_2                     matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        RateHzKnob_4Label               matlab.ui.control.Label
        RateHzKnob_4                    matlab.ui.control.Knob
        AmpKnob_4Label                  matlab.ui.control.Label
        AmpKnob_4                       matlab.ui.control.Knob
        PulseWidthKnob_2Label           matlab.ui.control.Label
        PulseWidthKnob_2                matlab.ui.control.Knob
        EnvelopesPanel_2                matlab.ui.container.Panel
        UIAxes_6                        matlab.ui.control.UIAxes
        ModulationEnvelopePanel_2       matlab.ui.container.Panel
        ASlider_3Label                  matlab.ui.control.Label
        ASlider_3                       matlab.ui.control.Slider
        DSlider_3Label                  matlab.ui.control.Label
        DSlider_3                       matlab.ui.control.Slider
        SSlider_3Label                  matlab.ui.control.Label
        SSlider_3                       matlab.ui.control.Slider
        RSlider_3Label                  matlab.ui.control.Label
        RSlider_3                       matlab.ui.control.Slider
        CurveKnob_3Label                matlab.ui.control.Label
        CurveKnob_3                     matlab.ui.control.Knob
        AmplitudeEnvelopePanel_2        matlab.ui.container.Panel
        ASlider_4Label                  matlab.ui.control.Label
        ASlider_4                       matlab.ui.control.Slider
        DSlider_4Label                  matlab.ui.control.Label
        DSlider_4                       matlab.ui.control.Slider
        SSlider_4Label                  matlab.ui.control.Label
        SSlider_4                       matlab.ui.control.Slider
        RSlider_4Label                  matlab.ui.control.Label
        RSlider_4                       matlab.ui.control.Slider
        CurveKnob_4Label                matlab.ui.control.Label
        CurveKnob_4                     matlab.ui.control.Knob
        VibratoPanel_2                  matlab.ui.container.Panel
        StartRateHzKnob_2Label          matlab.ui.control.Label
        StartRateHzKnob_2               matlab.ui.control.Knob
        EndRateHzKnob_2Label            matlab.ui.control.Label
        EndRateHzKnob_2                 matlab.ui.control.Knob
        StartAmtKnob_2Label             matlab.ui.control.Label
        StartAmtKnob_2                  matlab.ui.control.Knob
        EndAmtKnob_2Label               matlab.ui.control.Label
        EndAmtKnob_2                    matlab.ui.control.Knob
        EnvTimesKnob_2Label             matlab.ui.control.Label
        EnvTimesKnob_2                  matlab.ui.control.Knob
        GlobalModifiersPanel_2          matlab.ui.container.Panel
        StereoSpreadKnob_2Label         matlab.ui.control.Label
        StereoSpreadKnob_2              matlab.ui.control.Knob
    end


    properties (Access = private)
        
    end

    properties (Access = public)
        UDP   % UDP connection
        
        
        coarseFrequencies
        fineFrequencies
        modEnvAmt
        lfoADepth
        lfoBDepth
        outputLevels
    end

    methods (Access = private)
    
        function app = setCoarseFrequencyKnobs(app)
            app.Osc1Knob.Value = num2str(app.coarseFrequencies(1));
            app.Osc2Knob.Value = num2str(app.coarseFrequencies(2));
            app.Osc3Knob.Value = num2str(app.coarseFrequencies(3));
            app.Osc4Knob.Value = num2str(app.coarseFrequencies(4));
            app.Osc5Knob.Value = num2str(app.coarseFrequencies(5));
            app.Osc6Knob.Value = num2str(app.coarseFrequencies(6));
        end
        
        function app = setFineFrequencyKnobs(app)
            app.Osc1Knob_2.Value = app.fineFrequencies(1);
            app.Osc2Knob_2.Value = app.fineFrequencies(2);
            app.Osc3Knob_2.Value = app.fineFrequencies(3);
            app.Osc4Knob_2.Value = app.fineFrequencies(4);
            app.Osc5Knob_2.Value = app.fineFrequencies(5);
            app.Osc6Knob_2.Value = app.fineFrequencies(6);
        end
        
        function app = setModEnvAmtKnobs(app)
            app.Osc1Knob_3.Value = app.modEnvAmt(1);
            app.Osc2Knob_3.Value = app.modEnvAmt(2);
            app.Osc3Knob_3.Value = app.modEnvAmt(3);
            app.Osc4Knob_3.Value = app.modEnvAmt(4);
            app.Osc5Knob_3.Value = app.modEnvAmt(5);
            app.Osc6Knob_3.Value = app.modEnvAmt(6);
        end
        
        function app = setLfoADepthKnobs(app)
            app.Osc1Knob_4.Value = app.lfoADepth(1);
            app.Osc2Knob_4.Value = app.lfoADepth(2);
            app.Osc3Knob_4.Value = app.lfoADepth(3);
            app.Osc4Knob_4.Value = app.lfoADepth(4);
            app.Osc5Knob_4.Value = app.lfoADepth(5);
            app.Osc6Knob_4.Value = app.lfoADepth(6);
        end
        
        function app = setLfoBDepthKnobs(app)
            app.Osc1Knob_5.Value = app.lfoBDepth(1);
            app.Osc2Knob_5.Value = app.lfoBDepth(2);
            app.Osc3Knob_5.Value = app.lfoBDepth(3);
            app.Osc4Knob_5.Value = app.lfoBDepth(4);
            app.Osc5Knob_5.Value = app.lfoBDepth(5);
            app.Osc6Knob_5.Value = app.lfoBDepth(6);
        end
        
        function app = setOutputLevelSliders(app)
            app.Osc1Slider.Value = app.outputLevels(1);
            app.Osc2Slider.Value = app.outputLevels(2);
            app.Osc3Slider.Value = app.outputLevels(3);
            app.Osc4Slider.Value = app.outputLevels(4);
            app.Osc5Slider.Value = app.outputLevels(5);
            app.Osc6Slider.Value = app.outputLevels(6);
        end
        
    end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UDP = udp('127.0.0.1',57120); 
            fopen(app.UDP); 
            
            app.coarseFrequencies = [1.0, 1.0, 1.0, 1.0, 1.0, 1.0]; 
            app.fineFrequencies = [0.01, 0.0, 0.2, 0.1, 0.0, 0.0]; 
            app.modEnvAmt = [ 0.0, 0.1, 0.0, 0.3, 0.4, 0.0];
            app.lfoADepth = [ 0.0, 0.1, 0.0, 0.3, 0.4, 0.0];
            app.lfoBDepth = [ 0.0, 0.1, 0.0, 0.3, 0.4, 0.0];
            app.outputLevels = [ 1.0, 1.0, 0.0, 0.3, 0.4, 0.0];
            
            %initialise Knobs with corect values
            setCoarseFrequencyKnobs(app);
            setFineFrequencyKnobs(app);
            setModEnvAmtKnobs(app);
            setLfoADepthKnobs(app);
            setLfoBDepthKnobs(app);
            setOutputLevelSliders(app);
        end

        % Value changed function: Osc1Knob
        function Osc1KnobValueChanged(app, event)
            app.coarseFrequencies(1) = str2double(app.Osc1Knob.Value);
            %disp(mat2str(app.coarseFrequencies))
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
        end

        % Value changed function: Osc2Knob
        function Osc2KnobValueChanged(app, event)
            app.coarseFrequencies(2) = str2double(app.Osc2Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
        end

        % Value changed function: Osc3Knob
        function Osc3KnobValueChanged(app, event)
            app.coarseFrequencies(3) = str2double(app.Osc3Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
        end

        % Value changed function: Osc4Knob
        function Osc4KnobValueChanged(app, event)
            app.coarseFrequencies(4) = str2double(app.Osc4Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
        end

        % Value changed function: Osc5Knob
        function Osc5KnobValueChanged(app, event)
            app.coarseFrequencies(5) = str2double(app.Osc5Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
        end

        % Value changed function: Osc6Knob
        function Osc6KnobValueChanged(app, event)
            app.coarseFrequencies(6) = str2double(app.Osc6Knob.Value);
            sendParamsOverOSC(app.coarseFrequencies, '/freqCoarse', 'ffffff', app.UDP)
        end

        % Value changing function: Osc1Knob_2
        function Osc1Knob_2ValueChanging(app, event)
            app.fineFrequencies(1) = event.Value;
            %disp(mat2str(app.fineFrequencies))
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
        end

        % Value changing function: Osc2Knob_2
        function Osc2Knob_2ValueChanging(app, event)
            app.fineFrequencies(2) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
        end

        % Value changing function: Osc3Knob_2
        function Osc3Knob_2ValueChanging(app, event)
            app.fineFrequencies(3) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
        end

        % Value changing function: Osc4Knob_2
        function Osc4Knob_2ValueChanging(app, event)
            app.fineFrequencies(4) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
        end

        % Value changing function: Osc5Knob_2
        function Osc5Knob_2ValueChanging(app, event)
            app.fineFrequencies(5) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
        end

        % Value changing function: Osc6Knob_2
        function Osc6Knob_2ValueChanging(app, event)
            app.fineFrequencies(6) = event.Value;
            sendParamsOverOSC(app.fineFrequencies, '/freqFine', 'ffffff', app.UDP)
        end

        % Value changing function: Osc1Knob_3
        function Osc1Knob_3ValueChanging(app, event)
            app.modEnvAmt(1) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
        end

        % Value changing function: Osc2Knob_3
        function Osc2Knob_3ValueChanging(app, event)
            app.modEnvAmt(2) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
        end

        % Value changing function: Osc3Knob_3
        function Osc3Knob_3ValueChanging(app, event)
            app.modEnvAmt(3) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
        end

        % Value changing function: Osc4Knob_3
        function Osc4Knob_3ValueChanging(app, event)
            app.modEnvAmt(4) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
        end

        % Value changing function: Osc5Knob_3
        function Osc5Knob_3ValueChanging(app, event)
            app.modEnvAmt(5) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
        end

        % Value changing function: Osc6Knob_3
        function Osc6Knob_3ValueChanging(app, event)
            app.modEnvAmt(6) = event.Value;
            sendParamsOverOSC(app.modEnvAmt, '/envAmt', 'ffffff', app.UDP)
        end

        % Value changing function: Osc1Knob_4
        function Osc1Knob_4ValueChanging(app, event)
            app.lfoADepth(1) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc2Knob_4
        function Osc2Knob_4ValueChanging(app, event)
            app.lfoADepth(2) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc3Knob_4
        function Osc3Knob_4ValueChanging(app, event)
            app.lfoADepth(3) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc4Knob_4
        function Osc4Knob_4ValueChanging(app, event)
            app.lfoADepth(4) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc5Knob_4
        function Osc5Knob_4ValueChanging(app, event)
            app.lfoADepth(5) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc6Knob_4
        function Osc6Knob_4ValueChanging(app, event)
            app.lfoADepth(6) = event.Value;
            sendParamsOverOSC(app.lfoADepth, '/lfoADepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc1Knob_5
        function Osc1Knob_5ValueChanging(app, event)
            app.lfoBDepth(1) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc2Knob_5
        function Osc2Knob_5ValueChanging(app, event)
            app.lfoBDepth(2) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc3Knob_5
        function Osc3Knob_5ValueChanging(app, event)
            app.lfoBDepth(3) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc4Knob_5
        function Osc4Knob_5ValueChanging(app, event)
            app.lfoBDepth(4) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc5Knob_5
        function Osc5Knob_5ValueChanging(app, event)
            app.lfoBDepth(5) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc6Knob_5
        function Osc6Knob_5ValueChanging(app, event)
            app.lfoBDepth(6) = event.Value;
            sendParamsOverOSC(app.lfoBDepth, '/lfoBDepth', 'ffffff', app.UDP)
        end

        % Value changing function: Osc1Slider
        function Osc1SliderValueChanging(app, event)
            app.outputLevels(1) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
        end

        % Value changing function: Osc2Slider
        function Osc2SliderValueChanging(app, event)
            app.outputLevels(2) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
        end

        % Value changing function: Osc3Slider
        function Osc3SliderValueChanging(app, event)
            app.outputLevels(3) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
        end

        % Value changing function: Osc4Slider
        function Osc4SliderValueChanging(app, event)
            app.outputLevels(4) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
        end

        % Value changing function: Osc5Slider
        function Osc5SliderValueChanging(app, event)
            app.outputLevels(5) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
        end

        % Value changing function: Osc6Slider
        function Osc6SliderValueChanging(app, event)
            app.outputLevels(6) = event.Value;
            sendParamsOverOSC(app.outputLevels, '/outputLevels', 'ffffff', app.UDP)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimbreControls1Tab = uitab(app.TabGroup);
            app.TimbreControls1Tab.Title = 'Timbre Controls 1 ';
            app.TimbreControls1Tab.Units = 'pixels';
            app.CoarseFrequencyPanel = uipanel(app.TimbreControls1Tab);
            app.CoarseFrequencyPanel.Title = 'Coarse Frequency';
            app.Osc1KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc1KnobLabel.Text = 'Osc1';
            app.Osc1Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc1Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc1Knob.ValueChangedFcn = createCallbackFcn(app, @Osc1KnobValueChanged, true);
            app.Osc1Knob.Value = '0.5';
            app.Osc6KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc6KnobLabel.Text = 'Osc6';
            app.Osc6Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc6Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc6Knob.ValueChangedFcn = createCallbackFcn(app, @Osc6KnobValueChanged, true);
            app.Osc6Knob.Value = '0.5';
            app.Osc5KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc5KnobLabel.Text = 'Osc5';
            app.Osc5Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc5Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc5Knob.ValueChangedFcn = createCallbackFcn(app, @Osc5KnobValueChanged, true);
            app.Osc5Knob.Value = '0.5';
            app.Osc2KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc2KnobLabel.Text = 'Osc2';
            app.Osc2Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc2Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc2Knob.ValueChangedFcn = createCallbackFcn(app, @Osc2KnobValueChanged, true);
            app.Osc2Knob.Value = '0.5';
            app.Osc3KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc3KnobLabel.Text = 'Osc3';
            app.Osc3Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc3Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc3Knob.ValueChangedFcn = createCallbackFcn(app, @Osc3KnobValueChanged, true);
            app.Osc3Knob.Value = '0.5';
            app.Osc4KnobLabel = uilabel(app.CoarseFrequencyPanel);
            app.Osc4KnobLabel.Text = 'Osc4';
            app.Osc4Knob = uiknob(app.CoarseFrequencyPanel, 'discrete');
            app.Osc4Knob.Items = {'0.5', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Osc4Knob.ValueChangedFcn = createCallbackFcn(app, @Osc4KnobValueChanged, true);
            app.Osc4Knob.Value = '0.5';
            app.FineFrequencyPanel = uipanel(app.TimbreControls1Tab);
            app.FineFrequencyPanel.Title = 'Fine Frequency';
            app.Osc1Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc1Knob_2Label.Text = 'Osc1';
            app.Osc1Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc1Knob_2.Limits = [0 1];
            app.Osc1Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_2ValueChanging, true);
            app.Osc2Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc2Knob_2Label.Text = 'Osc2';
            app.Osc2Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc2Knob_2.Limits = [0 1];
            app.Osc2Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_2ValueChanging, true);
            app.Osc3Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc3Knob_2Label.Text = 'Osc3';
            app.Osc3Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc3Knob_2.Limits = [0 1];
            app.Osc3Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_2ValueChanging, true);
            app.Osc4Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc4Knob_2Label.Text = 'Osc4';
            app.Osc4Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc4Knob_2.Limits = [0 1];
            app.Osc4Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_2ValueChanging, true);
            app.Osc5Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc5Knob_2Label.Text = 'Osc5';
            app.Osc5Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc5Knob_2.Limits = [0 1];
            app.Osc5Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_2ValueChanging, true);
            app.Osc6Knob_2Label = uilabel(app.FineFrequencyPanel);
            app.Osc6Knob_2Label.Text = 'Osc6';
            app.Osc6Knob_2 = uiknob(app.FineFrequencyPanel, 'continuous');
            app.Osc6Knob_2.Limits = [0 1];
            app.Osc6Knob_2.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_2ValueChanging, true);
            app.ModEnvAmtPanel = uipanel(app.TimbreControls1Tab);
            app.ModEnvAmtPanel.Title = 'Mod Env Amt';
            app.Osc1Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc1Knob_3Label.Text = 'Osc1';
            app.Osc1Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc1Knob_3.Limits = [0 1];
            app.Osc1Knob_3.MajorTicks = [0 0.5 1];
            app.Osc1Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_3ValueChanging, true);
            app.Osc2Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc2Knob_3Label.Text = 'Osc2';
            app.Osc2Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc2Knob_3.Limits = [0 1];
            app.Osc2Knob_3.MajorTicks = [0 0.5 1];
            app.Osc2Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_3ValueChanging, true);
            app.Osc3Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc3Knob_3Label.Text = 'Osc3';
            app.Osc3Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc3Knob_3.Limits = [0 1];
            app.Osc3Knob_3.MajorTicks = [0 0.5 1];
            app.Osc3Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_3ValueChanging, true);
            app.Osc4Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc4Knob_3Label.Text = 'Osc4';
            app.Osc4Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc4Knob_3.Limits = [0 1];
            app.Osc4Knob_3.MajorTicks = [0 0.5 1];
            app.Osc4Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_3ValueChanging, true);
            app.Osc5Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc5Knob_3Label.Text = 'Osc5';
            app.Osc5Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc5Knob_3.Limits = [0 1];
            app.Osc5Knob_3.MajorTicks = [0 0.5 1];
            app.Osc5Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_3ValueChanging, true);
            app.Osc6Knob_3Label = uilabel(app.ModEnvAmtPanel);
            app.Osc6Knob_3Label.Text = 'Osc6';
            app.Osc6Knob_3 = uiknob(app.ModEnvAmtPanel, 'continuous');
            app.Osc6Knob_3.Limits = [0 1];
            app.Osc6Knob_3.MajorTicks = [0 0.5 1];
            app.Osc6Knob_3.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_3ValueChanging, true);
            app.OutputLevelsPanel = uipanel(app.TimbreControls1Tab);
            app.OutputLevelsPanel.Title = 'Output Levels';
            app.Osc1SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc1SliderLabel.Text = 'Osc1';
            app.Osc1Slider = uislider(app.OutputLevelsPanel);
            app.Osc1Slider.Limits = [0 1];
            app.Osc1Slider.ValueChangingFcn = createCallbackFcn(app, @Osc1SliderValueChanging, true);
            app.Osc2SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc2SliderLabel.Text = 'Osc2';
            app.Osc2Slider = uislider(app.OutputLevelsPanel);
            app.Osc2Slider.Limits = [0 1];
            app.Osc2Slider.ValueChangingFcn = createCallbackFcn(app, @Osc2SliderValueChanging, true);
            app.Osc3SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc3SliderLabel.Text = 'Osc3';
            app.Osc3Slider = uislider(app.OutputLevelsPanel);
            app.Osc3Slider.Limits = [0 1];
            app.Osc3Slider.ValueChangingFcn = createCallbackFcn(app, @Osc3SliderValueChanging, true);
            app.Osc4SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc4SliderLabel.Text = 'Osc4';
            app.Osc4Slider = uislider(app.OutputLevelsPanel);
            app.Osc4Slider.Limits = [0 1];
            app.Osc4Slider.ValueChangingFcn = createCallbackFcn(app, @Osc4SliderValueChanging, true);
            app.Osc5SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc5SliderLabel.Text = 'Osc5';
            app.Osc5Slider = uislider(app.OutputLevelsPanel);
            app.Osc5Slider.Limits = [0 1];
            app.Osc5Slider.ValueChangingFcn = createCallbackFcn(app, @Osc5SliderValueChanging, true);
            app.Osc6SliderLabel = uilabel(app.OutputLevelsPanel);
            app.Osc6SliderLabel.Text = 'Osc6';
            app.Osc6Slider = uislider(app.OutputLevelsPanel);
            app.Osc6Slider.Limits = [0 1];
            app.Osc6Slider.ValueChangingFcn = createCallbackFcn(app, @Osc6SliderValueChanging, true);
            app.LFOADepthPanel = uipanel(app.TimbreControls1Tab);
            app.LFOADepthPanel.Title = 'LFO A Depth';
            app.Osc1Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc1Knob_4Label.Text = 'Osc1';
            app.Osc1Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc1Knob_4.Limits = [0 1];
            app.Osc1Knob_4.MajorTicks = [0 0.5 1];
            app.Osc1Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_4ValueChanging, true);
            app.Osc2Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc2Knob_4Label.Text = 'Osc2';
            app.Osc2Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc2Knob_4.Limits = [0 1];
            app.Osc2Knob_4.MajorTicks = [0 0.5 1];
            app.Osc2Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_4ValueChanging, true);
            app.Osc3Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc3Knob_4Label.Text = 'Osc3';
            app.Osc3Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc3Knob_4.Limits = [0 1];
            app.Osc3Knob_4.MajorTicks = [0 0.5 1];
            app.Osc3Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_4ValueChanging, true);
            app.Osc4Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc4Knob_4Label.Text = 'Osc4';
            app.Osc4Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc4Knob_4.Limits = [0 1];
            app.Osc4Knob_4.MajorTicks = [0 0.5 1];
            app.Osc4Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_4ValueChanging, true);
            app.Osc5Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc5Knob_4Label.Text = 'Osc5';
            app.Osc5Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc5Knob_4.Limits = [0 1];
            app.Osc5Knob_4.MajorTicks = [0 0.5 1];
            app.Osc5Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_4ValueChanging, true);
            app.Osc6Knob_4Label = uilabel(app.LFOADepthPanel);
            app.Osc6Knob_4Label.Text = 'Osc6';
            app.Osc6Knob_4 = uiknob(app.LFOADepthPanel, 'continuous');
            app.Osc6Knob_4.Limits = [0 1];
            app.Osc6Knob_4.MajorTicks = [0 0.5 1];
            app.Osc6Knob_4.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_4ValueChanging, true);
            app.LFOBDepthPanel = uipanel(app.TimbreControls1Tab);
            app.LFOBDepthPanel.Title = 'LFO B Depth';
            app.Osc1Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc1Knob_5Label.Text = 'Osc1';
            app.Osc1Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc1Knob_5.Limits = [0 1];
            app.Osc1Knob_5.MajorTicks = [0 0.5 1];
            app.Osc1Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc1Knob_5ValueChanging, true);
            app.Osc2Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc2Knob_5Label.Text = 'Osc2';
            app.Osc2Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc2Knob_5.Limits = [0 1];
            app.Osc2Knob_5.MajorTicks = [0 0.5 1];
            app.Osc2Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc2Knob_5ValueChanging, true);
            app.Osc3Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc3Knob_5Label.Text = 'Osc3';
            app.Osc3Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc3Knob_5.Limits = [0 1];
            app.Osc3Knob_5.MajorTicks = [0 0.5 1];
            app.Osc3Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc3Knob_5ValueChanging, true);
            app.Osc4Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc4Knob_5Label.Text = 'Osc4';
            app.Osc4Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc4Knob_5.Limits = [0 1];
            app.Osc4Knob_5.MajorTicks = [0 0.5 1];
            app.Osc4Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc4Knob_5ValueChanging, true);
            app.Osc5Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc5Knob_5Label.Text = 'Osc5';
            app.Osc5Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc5Knob_5.Limits = [0 1];
            app.Osc5Knob_5.MajorTicks = [0 0.5 1];
            app.Osc5Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc5Knob_5ValueChanging, true);
            app.Osc6Knob_5Label = uilabel(app.LFOBDepthPanel);
            app.Osc6Knob_5Label.Text = 'Osc6';
            app.Osc6Knob_5 = uiknob(app.LFOBDepthPanel, 'continuous');
            app.Osc6Knob_5.Limits = [0 1];
            app.Osc6Knob_5.MajorTicks = [0 0.5 1];
            app.Osc6Knob_5.ValueChangingFcn = createCallbackFcn(app, @Osc6Knob_5ValueChanging, true);
            app.TimbreControls2Tab = uitab(app.TabGroup);
            app.TimbreControls2Tab.Title = 'Timbre Controls 2';
            app.TimbreControls2Tab.Units = 'pixels';
            app.PhaseModulationParametersPanel = uipanel(app.TimbreControls2Tab);
            app.PhaseModulationParametersPanel.Title = 'Phase Modulation Parameters';
            app.Osc11KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc11KnobLabel.Text = 'Osc1->1';
            app.Osc11Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc11Knob.Limits = [0 10];
            app.Osc11Knob.MajorTicks = [0 10];
            app.Osc12KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc12KnobLabel.Text = 'Osc1->2';
            app.Osc12Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc12Knob.Limits = [0 10];
            app.Osc12Knob.MajorTicks = [0 10];
            app.Osc13KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc13KnobLabel.Text = 'Osc1->3';
            app.Osc13Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc13Knob.Limits = [0 10];
            app.Osc13Knob.MajorTicks = [0 10];
            app.Osc14KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc14KnobLabel.Text = 'Osc1->4';
            app.Osc14Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc14Knob.Limits = [0 10];
            app.Osc14Knob.MajorTicks = [0 10];
            app.Osc15KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc15KnobLabel.Text = 'Osc1->5';
            app.Osc15Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc15Knob.Limits = [0 10];
            app.Osc15Knob.MajorTicks = [0 10];
            app.Osc16KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc16KnobLabel.Text = 'Osc1->6';
            app.Osc16Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc16Knob.Limits = [0 10];
            app.Osc16Knob.MajorTicks = [0 10];
            app.Osc21KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc21KnobLabel.Text = 'Osc2->1';
            app.Osc21Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc21Knob.Limits = [0 10];
            app.Osc21Knob.MajorTicks = [0 10];
            app.Osc22KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc22KnobLabel.Text = 'Osc2->2';
            app.Osc22Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc22Knob.Limits = [0 10];
            app.Osc22Knob.MajorTicks = [0 10];
            app.Osc23KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc23KnobLabel.Text = 'Osc2->3';
            app.Osc23Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc23Knob.Limits = [0 10];
            app.Osc23Knob.MajorTicks = [0 10];
            app.Osc24KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc24KnobLabel.Text = 'Osc2->4';
            app.Osc24Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc24Knob.Limits = [0 10];
            app.Osc24Knob.MajorTicks = [0 10];
            app.Osc25KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc25KnobLabel.Text = 'Osc2->5';
            app.Osc25Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc25Knob.Limits = [0 10];
            app.Osc25Knob.MajorTicks = [0 10];
            app.Osc26KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc26KnobLabel.Text = 'Osc2->6';
            app.Osc26Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc26Knob.Limits = [0 10];
            app.Osc26Knob.MajorTicks = [0 10];
            app.Osc31KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc31KnobLabel.Text = 'Osc3->1';
            app.Osc31Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc31Knob.Limits = [0 10];
            app.Osc31Knob.MajorTicks = [0 10];
            app.Osc32KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc32KnobLabel.Text = 'Osc3->2';
            app.Osc32Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc32Knob.Limits = [0 10];
            app.Osc32Knob.MajorTicks = [0 10];
            app.Osc33KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc33KnobLabel.Text = 'Osc3->3';
            app.Osc33Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc33Knob.Limits = [0 10];
            app.Osc33Knob.MajorTicks = [0 10];
            app.Osc34KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc34KnobLabel.Text = 'Osc3->4';
            app.Osc34Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc34Knob.Limits = [0 10];
            app.Osc34Knob.MajorTicks = [0 10];
            app.Osc35KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc35KnobLabel.Text = 'Osc3->5';
            app.Osc35Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc35Knob.Limits = [0 10];
            app.Osc35Knob.MajorTicks = [0 10];
            app.Osc36KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc36KnobLabel.Text = 'Osc3->6';
            app.Osc36Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc36Knob.Limits = [0 10];
            app.Osc36Knob.MajorTicks = [0 10];
            app.Osc41KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc41KnobLabel.Text = 'Osc4->1';
            app.Osc41Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc41Knob.Limits = [0 10];
            app.Osc41Knob.MajorTicks = [0 10];
            app.Osc42KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc42KnobLabel.Text = 'Osc4->2';
            app.Osc42Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc42Knob.Limits = [0 10];
            app.Osc42Knob.MajorTicks = [0 10];
            app.Osc43KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc43KnobLabel.Text = 'Osc4->3';
            app.Osc43Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc43Knob.Limits = [0 10];
            app.Osc43Knob.MajorTicks = [0 10];
            app.Osc44KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc44KnobLabel.Text = 'Osc4->4';
            app.Osc44Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc44Knob.Limits = [0 10];
            app.Osc44Knob.MajorTicks = [0 10];
            app.Osc45KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc45KnobLabel.Text = 'Osc4->5';
            app.Osc45Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc45Knob.Limits = [0 10];
            app.Osc45Knob.MajorTicks = [0 10];
            app.Osc46KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc46KnobLabel.Text = 'Osc4->6';
            app.Osc46Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc46Knob.Limits = [0 10];
            app.Osc46Knob.MajorTicks = [0 10];
            app.Osc51KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc51KnobLabel.Text = 'Osc5->1';
            app.Osc51Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc51Knob.Limits = [0 10];
            app.Osc51Knob.MajorTicks = [0 10];
            app.Osc52KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc52KnobLabel.Text = 'Osc5->2';
            app.Osc52Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc52Knob.Limits = [0 10];
            app.Osc52Knob.MajorTicks = [0 10];
            app.Osc53KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc53KnobLabel.Text = 'Osc5->3';
            app.Osc53Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc53Knob.Limits = [0 10];
            app.Osc53Knob.MajorTicks = [0 10];
            app.Osc54KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc54KnobLabel.Text = 'Osc5->4';
            app.Osc54Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc54Knob.Limits = [0 10];
            app.Osc54Knob.MajorTicks = [0 10];
            app.Osc55KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc55KnobLabel.Text = 'Osc5->5';
            app.Osc55Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc55Knob.Limits = [0 10];
            app.Osc55Knob.MajorTicks = [0 10];
            app.Osc56KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc56KnobLabel.Text = 'Osc5->6';
            app.Osc56Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc56Knob.Limits = [0 10];
            app.Osc56Knob.MajorTicks = [0 10];
            app.Osc61KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc61KnobLabel.Text = 'Osc6->1';
            app.Osc61Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc61Knob.Limits = [0 10];
            app.Osc61Knob.MajorTicks = [0 10];
            app.Osc62KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc62KnobLabel.Text = 'Osc6->2';
            app.Osc62Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc62Knob.Limits = [0 10];
            app.Osc62Knob.MajorTicks = [0 10];
            app.Osc63KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc63KnobLabel.Text = 'Osc6->3';
            app.Osc63Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc63Knob.Limits = [0 10];
            app.Osc63Knob.MajorTicks = [0 10];
            app.Osc64KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc64KnobLabel.Text = 'Osc6->4';
            app.Osc64Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc64Knob.Limits = [0 10];
            app.Osc64Knob.MajorTicks = [0 10];
            app.Osc65KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc65KnobLabel.Text = 'Osc6->5';
            app.Osc65Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc65Knob.Limits = [0 10];
            app.Osc65Knob.MajorTicks = [0 10];
            app.Osc66KnobLabel = uilabel(app.PhaseModulationParametersPanel);
            app.Osc66KnobLabel.Text = 'Osc6->6';
            app.Osc66Knob = uiknob(app.PhaseModulationParametersPanel, 'continuous');
            app.Osc66Knob.Limits = [0 10];
            app.Osc66Knob.MajorTicks = [0 10];
            app.TimeControlsTab = uitab(app.TabGroup);
            app.TimeControlsTab.Title = 'Time Controls';
            app.TimeControlsTab.Units = 'pixels';
            app.LFOAPanel_2 = uipanel(app.TimeControlsTab);
            app.LFOAPanel_2.Title = 'LFO A';
            app.UIAxes_4 = uiaxes(app.LFOAPanel_2);
            title(app.UIAxes_4, 'LFO A Plot');
            xlabel(app.UIAxes_4, 'Time');
            ylabel(app.UIAxes_4, 'Amplitude');
            app.RateHzKnob_3Label = uilabel(app.LFOAPanel_2);
            app.RateHzKnob_3Label.Text = 'Rate (Hz)';
            app.RateHzKnob_3 = uiknob(app.LFOAPanel_2, 'continuous');
            app.RateHzKnob_3.Limits = [0 20];
            app.RateHzKnob_3.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.AmpKnob_3Label = uilabel(app.LFOAPanel_2);
            app.AmpKnob_3Label.Text = 'Amp';
            app.AmpKnob_3 = uiknob(app.LFOAPanel_2, 'continuous');
            app.AmpKnob_3.Limits = [0 1];
            app.AmpKnob_3.MajorTicks = [0 0.5 1];
            app.PhaseSpreadKnob_2Label = uilabel(app.LFOAPanel_2);
            app.PhaseSpreadKnob_2Label.Text = 'Phase Spread';
            app.PhaseSpreadKnob_2 = uiknob(app.LFOAPanel_2, 'continuous');
            app.PhaseSpreadKnob_2.Limits = [0 1];
            app.PhaseSpreadKnob_2.MajorTicks = [0 0.5 1];
            app.LFOBPanel_2 = uipanel(app.TimeControlsTab);
            app.LFOBPanel_2.Title = 'LFO B';
            app.UIAxes_5 = uiaxes(app.LFOBPanel_2);
            title(app.UIAxes_5, 'LFO B Plot');
            xlabel(app.UIAxes_5, 'Time');
            ylabel(app.UIAxes_5, 'Amplitude');
            app.RateHzKnob_4Label = uilabel(app.LFOBPanel_2);
            app.RateHzKnob_4Label.Text = 'Rate (Hz)';
            app.RateHzKnob_4 = uiknob(app.LFOBPanel_2, 'continuous');
            app.RateHzKnob_4.Limits = [0 20];
            app.RateHzKnob_4.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.AmpKnob_4Label = uilabel(app.LFOBPanel_2);
            app.AmpKnob_4Label.Text = 'Amp';
            app.AmpKnob_4 = uiknob(app.LFOBPanel_2, 'continuous');
            app.AmpKnob_4.Limits = [0 1];
            app.AmpKnob_4.MajorTicks = [0 0.5 1];
            app.PulseWidthKnob_2Label = uilabel(app.LFOBPanel_2);
            app.PulseWidthKnob_2Label.Text = 'Pulse Width';
            app.PulseWidthKnob_2 = uiknob(app.LFOBPanel_2, 'continuous');
            app.PulseWidthKnob_2.Limits = [0 1];
            app.PulseWidthKnob_2.MajorTicks = [0 0.5 1];
            app.EnvelopesPanel_2 = uipanel(app.TimeControlsTab);
            app.EnvelopesPanel_2.Title = 'Envelopes';
            app.UIAxes_6 = uiaxes(app.EnvelopesPanel_2);
            title(app.UIAxes_6, 'Envelopes Plot');
            xlabel(app.UIAxes_6, 'Time');
            ylabel(app.UIAxes_6, 'Amplitude');
            app.ModulationEnvelopePanel_2 = uipanel(app.EnvelopesPanel_2);
            app.ModulationEnvelopePanel_2.Title = 'Modulation Envelope';
            app.ASlider_3Label = uilabel(app.ModulationEnvelopePanel_2);
            app.ASlider_3Label.Text = 'A';
            app.ASlider_3 = uislider(app.ModulationEnvelopePanel_2);
            app.ASlider_3.Limits = [0 5];
            app.ASlider_3.MajorTickLabels = {'', '', '', '', '', ''};
            app.DSlider_3Label = uilabel(app.ModulationEnvelopePanel_2);
            app.DSlider_3Label.Text = 'D';
            app.DSlider_3 = uislider(app.ModulationEnvelopePanel_2);
            app.DSlider_3.Limits = [0 5];
            app.DSlider_3.MajorTickLabels = {'', '', '', '', '', ''};
            app.SSlider_3Label = uilabel(app.ModulationEnvelopePanel_2);
            app.SSlider_3Label.Text = 'S';
            app.SSlider_3 = uislider(app.ModulationEnvelopePanel_2);
            app.SSlider_3.Limits = [0 1];
            app.SSlider_3.MajorTickLabels = {'', '', '', '', ''};
            app.RSlider_3Label = uilabel(app.ModulationEnvelopePanel_2);
            app.RSlider_3Label.Text = 'R';
            app.RSlider_3 = uislider(app.ModulationEnvelopePanel_2);
            app.RSlider_3.Limits = [0 5];
            app.RSlider_3.MajorTickLabels = {'', '', '', '', '', ''};
            app.CurveKnob_3Label = uilabel(app.ModulationEnvelopePanel_2);
            app.CurveKnob_3Label.Text = 'Curve';
            app.CurveKnob_3 = uiknob(app.ModulationEnvelopePanel_2, 'continuous');
            app.CurveKnob_3.Limits = [-1 1];
            app.CurveKnob_3.MajorTicks = [-1 0 1];
            app.AmplitudeEnvelopePanel_2 = uipanel(app.EnvelopesPanel_2);
            app.AmplitudeEnvelopePanel_2.Title = 'Amplitude Envelope';
            app.ASlider_4Label = uilabel(app.AmplitudeEnvelopePanel_2);
            app.ASlider_4Label.Text = 'A';
            app.ASlider_4 = uislider(app.AmplitudeEnvelopePanel_2);
            app.ASlider_4.Limits = [0 5];
            app.ASlider_4.MajorTickLabels = {'', '', '', '', '', ''};
            app.DSlider_4Label = uilabel(app.AmplitudeEnvelopePanel_2);
            app.DSlider_4Label.Text = 'D';
            app.DSlider_4 = uislider(app.AmplitudeEnvelopePanel_2);
            app.DSlider_4.Limits = [0 5];
            app.DSlider_4.MajorTickLabels = {'', '', '', '', '', ''};
            app.SSlider_4Label = uilabel(app.AmplitudeEnvelopePanel_2);
            app.SSlider_4Label.Text = 'S';
            app.SSlider_4 = uislider(app.AmplitudeEnvelopePanel_2);
            app.SSlider_4.Limits = [0 1];
            app.SSlider_4.MajorTickLabels = {'', '', '', '', ''};
            app.RSlider_4Label = uilabel(app.AmplitudeEnvelopePanel_2);
            app.RSlider_4Label.Text = 'R';
            app.RSlider_4 = uislider(app.AmplitudeEnvelopePanel_2);
            app.RSlider_4.Limits = [0 5];
            app.RSlider_4.MajorTickLabels = {'', '', '', '', '', ''};
            app.CurveKnob_4Label = uilabel(app.AmplitudeEnvelopePanel_2);
            app.CurveKnob_4Label.Text = 'Curve';
            app.CurveKnob_4 = uiknob(app.AmplitudeEnvelopePanel_2, 'continuous');
            app.CurveKnob_4.Limits = [-1 1];
            app.CurveKnob_4.MajorTicks = [-1 0 1];
            app.VibratoPanel_2 = uipanel(app.TimeControlsTab);
            app.VibratoPanel_2.Title = 'Vibrato';
            app.StartRateHzKnob_2Label = uilabel(app.VibratoPanel_2);
            app.StartRateHzKnob_2Label.Text = 'Start Rate (Hz)';
            app.StartRateHzKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.StartRateHzKnob_2.Limits = [0 20];
            app.StartRateHzKnob_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.EndRateHzKnob_2Label = uilabel(app.VibratoPanel_2);
            app.EndRateHzKnob_2Label.Text = 'End Rate (Hz)';
            app.EndRateHzKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.EndRateHzKnob_2.Limits = [0 20];
            app.EndRateHzKnob_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.StartAmtKnob_2Label = uilabel(app.VibratoPanel_2);
            app.StartAmtKnob_2Label.Text = 'Start Amt';
            app.StartAmtKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.StartAmtKnob_2.Limits = [0 1];
            app.StartAmtKnob_2.MajorTicks = [0 0.5 1];
            app.EndAmtKnob_2Label = uilabel(app.VibratoPanel_2);
            app.EndAmtKnob_2Label.Text = 'End Amt';
            app.EndAmtKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.EndAmtKnob_2.Limits = [0 1];
            app.EndAmtKnob_2.MajorTicks = [0 0.5 1];
            app.EnvTimesKnob_2Label = uilabel(app.VibratoPanel_2);
            app.EnvTimesKnob_2Label.Text = 'Env Time (s)';
            app.EnvTimesKnob_2 = uiknob(app.VibratoPanel_2, 'continuous');
            app.EnvTimesKnob_2.Limits = [0 20];
            app.EnvTimesKnob_2.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.GlobalModifiersPanel_2 = uipanel(app.TimeControlsTab);
            app.GlobalModifiersPanel_2.Title = 'Global Modifiers';
            app.StereoSpreadKnob_2Label = uilabel(app.GlobalModifiersPanel_2);
            app.StereoSpreadKnob_2Label.Text = 'Stereo Spread';
            app.StereoSpreadKnob_2 = uiknob(app.GlobalModifiersPanel_2, 'continuous');
            app.StereoSpreadKnob_2.Limits = [0 1];
            app.StereoSpreadKnob_2.MajorTicks = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = SynthUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end